classdef MicroscopyBrowser_Amira < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        AmiraTab                       matlab.ui.container.Tab
        PathLabel                      matlab.ui.control.Label
        TrackPath                      matlab.ui.control.TextArea
        ChangeFolderButton             matlab.ui.control.Button
        FilesLabel                     matlab.ui.control.Label
        Files                          matlab.ui.control.ListBox
        SetTrackFile                   matlab.ui.control.Button
        infileLabel                    matlab.ui.control.Label
        N_rawTracks                    matlab.ui.control.NumericEditField
        TrackfileTextAreaLabel         matlab.ui.control.Label
        TrackfileTextArea              matlab.ui.control.TextArea
        minlengthLabel                 matlab.ui.control.Label
        setLength                      matlab.ui.control.NumericEditField
        minlengthLabel_2               matlab.ui.control.Label
        N_selectLength                 matlab.ui.control.NumericEditField
        ImagesCh1Label                 matlab.ui.control.Label
        ImageFolderTextArea            matlab.ui.control.TextArea
        SetImageFolder                 matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        N_images                       matlab.ui.control.NumericEditField
        ImagesLabel                    matlab.ui.control.Label
        Files_2                        matlab.ui.control.ListBox
        ImagePathLabel                 matlab.ui.control.Label
        Path_2                         matlab.ui.control.TextArea
        ChangeImageFolderButton        matlab.ui.control.Button
        ImagesCh2Label                 matlab.ui.control.Label
        ImageFolderTextAreaChannel2    matlab.ui.control.TextArea
        SetImageFolder_2               matlab.ui.control.Button
        Label_2                        matlab.ui.control.Label
        N_images_2                     matlab.ui.control.NumericEditField
        SetImageFolder_3               matlab.ui.control.Button
        ImagesCh3Label                 matlab.ui.control.Label
        ImageFolderTextAreaChannel3    matlab.ui.control.TextArea
        Label_3                        matlab.ui.control.Label
        N_images_3                     matlab.ui.control.NumericEditField
        Label_4                        matlab.ui.control.Label
        N_images_1                     matlab.ui.control.NumericEditField
        ClearButton                    matlab.ui.control.Button
        ClearButton_2                  matlab.ui.control.Button
        CheckBox                       matlab.ui.control.CheckBox
        CheckBox_2                     matlab.ui.control.CheckBox
        CheckBox_3                     matlab.ui.control.CheckBox
        CheckBox_4                     matlab.ui.control.CheckBox
        CheckBox_5                     matlab.ui.control.CheckBox
        CheckBox_6                     matlab.ui.control.CheckBox
        CheckBox_7                     matlab.ui.control.CheckBox
        CheckBox_8                     matlab.ui.control.CheckBox
        SetDetectionTrackFile          matlab.ui.control.Button
        DetectionTextAreaLabel         matlab.ui.control.Label
        DetectionTextArea              matlab.ui.control.TextArea
        CheckBox_9                     matlab.ui.control.CheckBox
        CheckBox_10                    matlab.ui.control.CheckBox
        CheckBox_11                    matlab.ui.control.CheckBox
        CatIDLabel                     matlab.ui.control.Label
        IsPSFLabel                     matlab.ui.control.Label
        resultframesLabel              matlab.ui.control.Label
        resultFrames                   matlab.ui.control.NumericEditField
        Label_5                        matlab.ui.control.Label
        SelectionDropDown              matlab.ui.control.DropDown
        dxEditFieldLabel               matlab.ui.control.Label
        Ch2dxEditField                 matlab.ui.control.NumericEditField
        dyEditFieldLabel               matlab.ui.control.Label
        Ch2dyEditField                 matlab.ui.control.NumericEditField
        dxEditFieldLabel_2             matlab.ui.control.Label
        Ch3dxEditField                 matlab.ui.control.NumericEditField
        dyEditFieldLabel_2             matlab.ui.control.Label
        Ch3dyEditField                 matlab.ui.control.NumericEditField
        ConfocalCheckBox               matlab.ui.control.CheckBox
        TrackInfoTab                   matlab.ui.container.Tab
        Channel2trackedCheckBox        matlab.ui.control.CheckBox
        Channel3trackedCheckBox        matlab.ui.control.CheckBox
        ChangeFilesButton_2            matlab.ui.control.Button
        AnalysisPathLabel              matlab.ui.control.Label
        analysisPath                   matlab.ui.control.TextArea
        StatusTextAreaLabel            matlab.ui.control.Label
        StatusTextArea                 matlab.ui.control.TextArea
        SaveDefaultsButton             matlab.ui.control.Button
        RUNButton                      matlab.ui.control.Button
        NameEditFieldLabel             matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        TrackEditFieldLabel            matlab.ui.control.Label
        TrackNumberEditField           matlab.ui.control.NumericEditField
        ImageEditFieldLabel            matlab.ui.control.Label
        ImageNumberEditField           matlab.ui.control.NumericEditField
        progressLabel                  matlab.ui.control.Label
        Progress                       matlab.ui.control.NumericEditField
        ROIwidthEditFieldLabel         matlab.ui.control.Label
        ROIwidthEditField              matlab.ui.control.NumericEditField
        ROIdepthEditFieldLabel         matlab.ui.control.Label
        ROIdepthEditField              matlab.ui.control.NumericEditField
        SaveorganizedtracksButton      matlab.ui.control.Button
        trackstoextractEditFieldLabel  matlab.ui.control.Label
        trackstoextractEditField       matlab.ui.control.NumericEditField
        extractROIsfromallframesCheckBox  matlab.ui.control.CheckBox
        WholeimageprojectionCheckBox   matlab.ui.control.CheckBox
        RUN_Detection_Button           matlab.ui.control.Button
        SaveTracksasTXTCheckBox        matlab.ui.control.CheckBox
        identicalxyforstaticROIsCheckBox  matlab.ui.control.CheckBox
    end


    properties (Access = private)
        nFiles % Number of tracking result file in current directory
        
        trackSelect % the indices selected from original track file
        rawTracks % structure track data from original file
        selectedTracks % reorganized structure of selected track data
        nFrames % Description
        imageFileNames % Description
        startpath % Description
        nMatFiles % Description
        matFileNames % Description
        nFolder % Description
        folderNames % Description
        imageFileNamesChannel2 % Description
        imageFileNamesChannel3 % Description
        imageSize % Description
        channel1nums % Description
        channel2nums % Description
        channel3nums % Description
        nFrames2 % Description
        nFrames3 % Description
        fileInfos % Description
        imageReference % Description
        analysisInfo % Description
    end

    methods (Access = private)


        function saveResultsForLabView(app,selectTracks,allROIs,resultFolder,mode)
            %
           switch mode
               case {0,1}
                     channel2 =  ~isequal(app.ImageFolderTextAreaChannel2.Value,"");
                     channel3 =  ~isequal(app.ImageFolderTextAreaChannel3.Value,"");
               case 2
                   channel2 =  ~isequal(app.Channel2EditField.Value,"");
                   channel3 =  ~isequal(app.Channel3EditField.Value,"");
               case 5
                   channel2 = false;
                   channel3 = false;
           end

            numberOfTracks = app.trackstoextractEditField.Value;
            for index = 1:numberOfTracks    %  Track Counter
                originalIndex = num2str(selectTracks(index).originalIndex, '%05.0f');
                selectIndex = num2str(index,'%05.0f');
                trackfoldername = strcat(selectIndex,"_",originalIndex);
                trackfolder = strcat(resultFolder,"/",trackfoldername);
                mkdir(char(trackfolder));
                trackLength = selectTracks(index).roisizes(7);
                sroi = selectTracks(index).roisizes(1);
                roiX = selectTracks(index).roisizes(1);
                roiY = selectTracks(index).roisizes(2);
                roiZ = selectTracks(index).roisizes(3);
                sX = selectTracks(index).roisizes(4);
                sY = selectTracks(index).roisizes(5);
                sZ = selectTracks(index).roisizes(6);
                if mode ~= 5
                    cROI = zeros(trackLength * sroi, 3*sroi);
                    XY = zeros(trackLength * sY, sX);
                    XZ = zeros(trackLength * sZ, sX);
                    YZ = zeros(trackLength * sY, sZ);
                    %   CHANNEL 2
                    if channel2
                        cROI2 = zeros(trackLength * sroi, 3*sroi);
                        XY2 = zeros(trackLength * sY, sX);
                        XZ2 = zeros(trackLength * sZ, sX);
                        YZ2 = zeros(trackLength * sY, sZ);
                    end
                    %   CHANNEL 3
                    if channel3
                        cROI3 = zeros(trackLength * sroi, 3*sroi);
                        XY3 = zeros(trackLength * sY, sX);
                        XZ3 = zeros(trackLength * sZ, sX);
                        YZ3 = zeros(trackLength * sY, sZ);
                    end
                else
                    cROI = zeros(trackLength * sroi, sroi);
                    XY = zeros(trackLength * sY, sX);
                end
                % making 2D arrays for easy saving
                for index2 = 1:trackLength     
                    %centered ROI
                    cROI((index2-1)*sroi+1:(index2-1)*sroi+roiY, 1:roiX) = allROIs(index).xy(:,:,index2);
                    if mode ~= 5
                        cROI((index2-1)*sroi+1:(index2-1)*sroi+roiZ, sroi+1:sroi+roiX) = allROIs(index).xz(:,:,index2);
                        cROI((index2-1)*sroi+1:(index2-1)*sroi+roiY, 2*sroi+1:2*sroi+roiZ) = allROIs(index).yz(:,:,index2);
                    end
                    
                    if (mode ~= 0) && (mode ~= 5)
                        %static ROI
                        XY((index2-1)*sY+1:(index2-1)*sY+sY, :) = allROIs(index).xyS(:,:,index2);
                        XZ((index2-1)*sZ+1:(index2-1)*sZ+sZ, :) = allROIs(index).xzS(:,:,index2);
                        YZ((index2-1)*sY+1:(index2-1)*sY+sY, :) = allROIs(index).yzS(:,:,index2);
                    end
                    % CHANNEL 2
                    if channel2
                        %centered ROI
                        cROI2((index2-1)*sroi+1:(index2-1)*sroi+roiY, 1:roiX) = allROIs(index).xy2(:,:,index2);
                        cROI2((index2-1)*sroi+1:(index2-1)*sroi+roiZ, sroi+1:sroi+roiX) = allROIs(index).xz2(:,:,index2);
                        cROI2((index2-1)*sroi+1:(index2-1)*sroi+roiY, 2*sroi+1:2*sroi+roiZ) = allROIs(index).yz2(:,:,index2);
                        if (mode ~= 0) && (mode ~= 5)
                            %static ROI
                            XY2((index2-1)*sY+1:(index2-1)*sY+sY, :) = allROIs(index).xyS2(:,:,index2);
                            XZ2((index2-1)*sZ+1:(index2-1)*sZ+sZ, :) = allROIs(index).xzS2(:,:,index2);
                            YZ2((index2-1)*sY+1:(index2-1)*sY+sY, :) = allROIs(index).yzS2(:,:,index2);
                        end
                    end
                    % CHANNEL 3
                    if channel3
                        %centered ROI
                        cROI3((index2-1)*sroi+1:(index2-1)*sroi+roiY, 1:roiX) = allROIs(index).xy3(:,:,index2);
                        cROI3((index2-1)*sroi+1:(index2-1)*sroi+roiZ, sroi+1:sroi+roiX) = allROIs(index).xz3(:,:,index2);
                        cROI3((index2-1)*sroi+1:(index2-1)*sroi+roiY, 2*sroi+1:2*sroi+roiZ) = allROIs(index).yz3(:,:,index2);
                        if (mode ~= 0) && (mode ~= 5)
                        %static ROI
                        XY3((index2-1)*sY+1:(index2-1)*sY+sY, :) = allROIs(index).xyS3(:,:,index2);
                        XZ3((index2-1)*sZ+1:(index2-1)*sZ+sZ, :) = allROIs(index).xzS3(:,:,index2);
                        YZ3((index2-1)*sY+1:(index2-1)*sY+sY, :) = allROIs(index).yzS3(:,:,index2);
                        end
                    end
                  end
                %
                sroiName = strcat(trackfolder,"/",selectIndex,"_",originalIndex,"_","cROI1");
                save(sroiName,'cROI');
                if app.SaveTracksasTXTCheckBox.Value
                    writematrix(cROI,sroiName);
                end
                %
                
                if (mode ~= 0) && (mode ~= 5)
                    xyName = strcat(trackfolder,"/",selectIndex,"_",originalIndex,"_","sXY1");
                    save(xyName,'XY');
                    if app.SaveTracksasTXTCheckBox.Value
                        writematrix(XY,xyName);
                    end
                    xzName = strcat(trackfolder,"/",selectIndex,"_",originalIndex,"_","sXZ1");
                    save(xzName,'XZ');
                    if app.SaveTracksasTXTCheckBox.Value
                        writematrix(XZ,xzName);
                    end
                    yzName = strcat(trackfolder,"/",selectIndex,"_",originalIndex,"_","sYZ1");
                    save(yzName,'YZ');
                    if app.SaveTracksasTXTCheckBox.Value
                        writematrix(YZ,yzName);
                    end
                end
                % CHANNEL 2
                if channel2
                    sroiName2 = strcat(trackfolder,"/",selectIndex,"_",originalIndex,"_","cROI2");
                    save(sroiName2,'cROI2');
                    if app.SaveTracksasTXTCheckBox.Value
                        writematrix(cROI2,sroiName2);
                    end
                    if (mode ~= 0) && (mode ~= 5)
                        xyName2 = strcat(trackfolder,"/",selectIndex,"_",originalIndex,"_","sXY2");
                        save(xyName2,'XY2');
                        if app.SaveTracksasTXTCheckBox.Value
                            writematrix(XY2,xyName2);
                        end
                        xzName2 = strcat(trackfolder,"/",selectIndex,"_",originalIndex,"_","sXZ2");
                        save(xzName2,'XZ2');
                        if app.SaveTracksasTXTCheckBox.Value
                            writematrix(XZ2,xzName2);
                        end
                        yzName2 = strcat(trackfolder,"/",selectIndex,"_",originalIndex,"_","sYZ2");
                        save(yzName2,'YZ2');
                        if app.SaveTracksasTXTCheckBox.Value
                            writematrix(YZ2,yzName2);
                        end
                    end
                end
                % CHANNEL 3
                if channel3
                    sroiName3 = strcat(trackfolder,"/",selectIndex,"_",originalIndex,"_","cROI3");
                    save(sroiName3,'cROI3');
                    if app.SaveTracksasTXTCheckBox.Value
                        writematrix(cROI3,sroiName3);
                    end
                    if (mode ~= 0) && (mode ~= 5)
                        xyName3 = strcat(trackfolder,"/",selectIndex,"_",originalIndex,"_","sXY3");
                        save(xyName3,'XY3');
                        if app.SaveTracksasTXTCheckBox.Value
                            writematrix(XY3,xyName3);
                        end
                        xzName3 = strcat(trackfolder,"/",selectIndex,"_",originalIndex,"_","sXZ3");
                        save(xzName3,'XZ3');
                        if app.SaveTracksasTXTCheckBox.Value
                            writematrix(XZ3,xzName3);
                        end
                        yzName3 = strcat(trackfolder,"/",selectIndex,"_",originalIndex,"_","sYZ3");
                        save(yzName3,'YZ3');
                        if app.SaveTracksasTXTCheckBox.Value
                            writematrix(YZ3,yzName3);
                        end
                    end
                end
            end

        end
        
        function cat8Bit = catIdxBinary(app)
            cat8Bit = app.CheckBox.Value + app.CheckBox_2.Value *2+app.CheckBox_3.Value*4+app.CheckBox_4.Value*8 ...
            +app.CheckBox_5.Value*16+app.CheckBox_6.Value*32+app.CheckBox_7.Value*64+app.CheckBox_8.Value*128;
            
        end
        
        function roiLimits12 = roiLimits(~,width,depth,xMax,yMax,zMax,x,y,z)
           %ROI size limits                 
           if (x-width) < 1 
                 xStart = 1 ;
            else xStart = (x-width);  
            end
            if (x+width) > xMax 
                 xEnd = xMax ;
            else xEnd = (x+width);  
            end
            if (y-width) < 1 
                 yStart = 1 ;
            else yStart = (y-width);  
            end
            if (y+width) > yMax 
                 yEnd = yMax ;
            else yEnd = (y+width);  
            end
            if (z-width) < 1 
                 zStart = 1 ;
            else zStart = (z-width);  
            end
            if (z+width) > zMax 
                 zEnd = zMax ;
            else zEnd = (z+width);  
            end
            % if projections of depth are used determine projection limits
            if depth >0
                if (x-depth) < 1 
                xPStart = 1 ;
                else xPStart = (x-depth);  
                end
                if (x+depth) > xMax 
                     xPEnd = xMax ;
                else xPEnd = (x+depth);  
                end
                if (y-depth) < 1 
                     yPStart = 1 ;
                else yPStart = (y-depth);  
                end
                if (y+depth) > yMax 
                     yPEnd = yMax ;
                else yPEnd = (y+depth);  
                end
                if (z-depth) < 1 
                     zPStart = 1 ;
                else zPStart = (z-depth);  
                end
                if (z+depth) > zMax 
                     zPEnd = zMax ;
                else zPEnd = (z+depth);  
                end
            else
                xPStart = x;
                xPEnd = x;
                yPStart = y;
                yPEnd = y;
                zPStart = z;
                zPEnd = z;
            end
        roiLimits12 = [xStart xEnd yStart yEnd zStart zEnd...
                       xPStart xPEnd yPStart yPEnd zPStart zPEnd];
            
        end
        
        function allnames = readFiles(app,parentfolder)
            folderInfo=dir(char(parentfolder));
            isFiles=~[folderInfo.isdir];
            allnames=string({folderInfo.name});
            allnames=allnames(isFiles);
        end
        
        function allnames = readFolders(app,parentfolder)
            folderInfo=dir(char(parentfolder));
            isFiles=[folderInfo.isdir];
            allnames=string({folderInfo.name});
            allnames=allnames(isFiles);
        end
        
        function series1 =  loadStack(app,imagePath)
                    imageData = bfopen(char(imagePath));
                    omeMeta = imageData{1, 4};
                    xMax = omeMeta.getPixelsSizeX(0).getValue(); % image width, pixels
                    yMax = omeMeta.getPixelsSizeY(0).getValue(); % image height, pixels
                    if app.ConfocalCheckBox.Value
                        zMax = omeMeta.getPixelsSizeZ(0).getValue();
                    else
                        zMax = omeMeta.getPixelsSizeT(0).getValue(); % number of Z slices  for whatever reason Z is saved in T
                    end
                    
                    %seriesCount = size(data, 1);
                    app.imageSize = [xMax yMax zMax];
                    series1 = imageData{1, 1};
                    %series1_planeCount = size(series1, 1);
        end
        
        function saveProjectedImages(app,proImg,ch,resultfolder,imageIndex)
            XY=proImg.XY;
            xyName = strcat(resultfolder,"/wholeimages/",num2str(imageIndex),"_","XY",num2str(ch));
            save(xyName,'XY');
            XZ=proImg.XZ;
            xyName = strcat(resultfolder,"/wholeimages/",num2str(imageIndex),"_","XZ",num2str(ch));
            save(xzName,'XZ');
            YZ=proImg.YZ;
            xyName = strcat(resultfolder,"/wholeimages/",num2str(imageIndex),"_","YZ",num2str(ch));
            save(yzName,'YZ'); 
        end


        function gaps = checkGaps(app,f)
            fnext = circshift(f,-1);
            gaps = (fnext -f)-1;
            gaps(end,1) = 0; 
        end
        
        function modyfiedCollumn = insertGaps(app,collumn, gaps, mode)
            gapsToAdd = sum(gaps);
            startSize = size(collumn,1);
            modyfiedCollumn = zeros(startSize+gapsToAdd,1);
            modyfiedCollumn(1) = collumn(1);
            newIndex =2;
            for index = 1 : startSize-1
                if gaps(index) > 0
                    switch mode
                        case 0
                            modyfiedCollumn(newIndex:newIndex+gaps(index)-1,1) = zeros(gaps(index,1),1);
                        case 1
                            modyfiedCollumn(newIndex:newIndex+gaps(index)-1,1) = [collumn(index)+1 : collumn(index)+gaps(index)];
                        case 2
                            modyfiedCollumn(newIndex:newIndex+gaps(index)-1,1) = collumn(index,1) * ones(gaps(index,1),1);
                    end
                    newIndex = newIndex + gaps(index,1);
                    modyfiedCollumn(newIndex,1) = collumn(index+1,1);
                    newIndex = newIndex +1;
                else
                    modyfiedCollumn(newIndex,1) = collumn(index+1,1);
                    newIndex = newIndex +1;
                end
            end
            modyfiedCollumn(end) = collumn(end);
            
        end
        
        
        function saveTracksText(app,filename)
            selectTracks = app.selectedTracks;
            arraysize = size(selectTracks,2);
            fields = fieldnames(selectTracks);
            numberFields = size(fields,1);
            fieldsString = string(fields(1));
            for index = 2:numberFields
                fieldsString = strcat(fieldsString,",",string(fields(index)));
            end
            %filename = 'D:\data\Maya_Data\temp\trackAll2.txt';
            % cellArray = {selectTracks(1).trackChannels,selectTracks(1).f;selectTracks(2).trackChannels,selectTracks(2).f};
            % writecell(cellArray,filename);
            fileID = fopen(filename,'w');
            
            % HEADER
            fprintf(fileID,'%d \n',4);
            fprintf(fileID,'%d \n',arraysize);
            fprintf(fileID,'%d \n',numberFields);
            fprintf(fileID,'%s \n',fieldsString);
            % TRACKS
            for index = 1:arraysize
                fprintf(fileID,'Track: %d \n',index);
                trackField = selectTracks(index);
                for index2 = 1:numberFields
                    var = getfield(trackField,char(fields(index2)))';
                    if isstring(var)
                        fprintf(fileID,'%s \n',var);
                    elseif isscalar(var)
                         fprintf(fileID,'%f \n',var);
                    else
                        fchar = char(sprintf('%f,',var));
                        fprintf(fileID,'%s \n',fchar(1:end-1));
                    end
                end
            %     
            
            end
            fclose(fileID);
        end


        function fillAnalysisInfo(app, caller)
        %  fills in the parameters from the GUI into the analysisInfo structure
        switch caller
            case 'Amira Track'
                app.analysisInfo.mode = 1;
                app.analysisInfo.confocal = app.ConfocalCheckBox.Value;
                app.analysisInfo.trackingFilePath = app.TrackfileTextArea.Value;
                app.analysisInfo.channel1Path = app.ImageFolderTextArea.Value;
                app.analysisInfo.channel2Path = app.ImageFolderTextAreaChannel2.Value;
                app.analysisInfo.channel3Path = app.ImageFolderTextAreaChannel3.Value;
                %[analysisInfo.filenames, ~] =  readImageFiles(analysisInfo.channel1Path);
%                 analysisInfo.filenames = analysisInfo.filenames';
%                 [analysisInfo.imageFileNamesChannel2, ~] = readImageFiles(analysisInfo.channel2Path);
%                 analysisInfo.imageFileNamesChannel2 = analysisInfo.imageFileNamesChannel2'; 
%                 analysisInfo.imageFileNamesChannel3 = [""];
                app.analysisInfo.setLength = app.setLength.Value;
                app.analysisInfo.cat8bit = catIdxBinary(app);
                app.analysisInfo.Ch2dx = app.Ch2dxEditField.Value;
                app.analysisInfo.Ch2dy = app.Ch2dyEditField.Value;
                app.analysisInfo.Ch3dx = app.Ch3dxEditField.Value;
                app.analysisInfo.Ch3dy = app.Ch3dyEditField.Value;
                app.analysisInfo.Wholeimageprojection = 0;
                app.analysisInfo.identicalsROIxy = app.identicalxyforstaticROIsCheckBox.Value;

            case 'Amira Detection'
                app.analysisInfo.mode = 0;
                app.analysisInfo.confocal = app.ConfocalCheckBox.Value;
                app.analysisInfo.trackingFilePath = app.DetectionTextArea.Value;
                app.analysisInfo.channel1Path = app.ImageFolderTextArea.Value;
                app.analysisInfo.channel2Path = app.ImageFolderTextAreaChannel2.Value;
                app.analysisInfo.channel3Path = app.ImageFolderTextAreaChannel3.Value;
                %[analysisInfo.filenames, ~] =  readImageFiles(analysisInfo.channel1Path);
%                 analysisInfo.filenames = analysisInfo.filenames';
%                 [analysisInfo.imageFileNamesChannel2, ~] = readImageFiles(analysisInfo.channel2Path);
%                 analysisInfo.imageFileNamesChannel2 = analysisInfo.imageFileNamesChannel2'; 
%                 analysisInfo.imageFileNamesChannel3 = [""];
                app.analysisInfo.setLength = app.setLength.Value;
                app.analysisInfo.cat8bit = catIdxBinary(app);
                app.analysisInfo.Ch2dx = app.Ch2dxEditField.Value;
                app.analysisInfo.Ch2dy = app.Ch2dyEditField.Value;
                app.analysisInfo.Ch3dx = app.Ch3dxEditField.Value;
                app.analysisInfo.Ch3dy = app.Ch3dyEditField.Value;
                app.analysisInfo.Wholeimageprojection = 0;
                app.analysisInfo.isPSF8bit = int8(app.CheckBox_9.Value)+int8(app.CheckBox_10.Value)*2+int8(app.CheckBox_11.Value)*4;
                if app.SelectionDropDown.Value == 'AND'
                    app.analysisInfo.isPSF8bit = app.analysisInfo.isPSF8bit + 8;
                end
            
        end
        app.analysisInfo.analysisPath = app.analysisPath.Value;
        app.analysisInfo.nameAddon = app.NameEditField.Value;
        app.analysisInfo.roiWidth = app.ROIwidthEditField.Value;
        app.analysisInfo.roiWidthP = app.ROIdepthEditField.Value;
        app.analysisInfo.allROIs = app.extractROIsfromallframesCheckBox.Value;
        app.analysisInfo.SaveTracksasTXTCheckBox = app.SaveTracksasTXTCheckBox.Value;
        app.analysisInfo.Run = 0;
      end

        
        function results = readFileList(app, selpath)
            app.TrackPath.Value = selpath;
            fileList = readFiles(app,selpath)';
            app.nFiles=size(fileList,1);
            fileIndices=[1:app.nFiles];
            %disp(fitsFileList)
            app.Files.Items = fileList;
            app.Files.ItemsData = fileIndices;
            %
%             fileList = fileList(contains(fileList,".csv"));
%             if size(fileList,1) ~= 0
%                 fileList = fileList(~contains(fileList,"._"));
%                 fileList = sort(fileList);
%                 independentChannels = unique(extractBefore(fileList,"_"));
%                 app.versionsListBox.Items = independentChannels;
%                 app.versionsListBox.ItemsData = [1:size(independentChannels)];
%                 fileList = extractAfter(fileList(contains(fileList,independentChannels(1)+"_")),independentChannels(1)+"_");
%                app.nCSVFiles = size(fileList,1);
%                 app.Files_3.Items = fileList;
%                 app.Files_3.ItemsData = [1:app.nCSVFiles];
%                 if app.nCSVFiles > 0
%                     app.SetCVSFolder.Enable = true;
%                 end
%             else
%                 app.nCSVFiles = 0;
%                 app.Files_3.Items = "";
%                 app.Files_3.ItemsData =0; 
%                 app.SetCVSFolder.Enable = false;
%             end
%             app.N_rawTracks_2.Value = app.nCSVFiles;
            app.startpath = selpath;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('matlab/bfmatlab'));
            if exist('virusbrowser_def.mat',"file") == 2
                load('virusbrowser_def.mat','defaults');
                app.TrackPath.Value = defaults.trackpath;
                app.analysisPath.Value = defaults.analysispath;
                app.Path_2.Value = defaults.imagefolder1;
                app.setLength.Value = defaults.minlength;
                app.CheckBox.Value = defaults.cat1;
                app.CheckBox_2.Value = defaults.cat2;
                app.CheckBox_3.Value = defaults.cat3;
                app.CheckBox_4.Value = defaults.cat4;
                app.CheckBox_5.Value = defaults.cat5;
                app.CheckBox_6.Value = defaults.cat6;
                app.CheckBox_7.Value = defaults.cat7;
                app.CheckBox_8.Value = defaults.cat8;
                app.ROIwidthEditField.Value = defaults.roiwidth;
                app.ROIdepthEditField.Value = defaults.roidepth;
            else
                app.TrackPath.Value = '';
            end
            
       
            selpath = char(app.TrackPath.Value);

             if selpath ~= 0          
                app.TrackPath.Value = selpath;
                fileList = readFiles(app,selpath)';
                app.nFiles=size(fileList,1);
                fileIndices=[1:app.nFiles];
           
                app.Files.Items = fileList;
                app.Files.ItemsData = fileIndices;
                %

             end
            %read in Images
            selpath = app.Path_2.Value;
            app.Path_2.Value = selpath;
            fileList = readFiles(app,selpath)'; 
            fileListTIF = fileList(contains(fileList,".tif"));
            app.nFrames=size(fileListTIF,1);
            app.N_images.Value = app.nFrames;
            fileIndices=[1:app.nFrames];
            %disp(fitsFileList)
            app.Files_2.Items = fileListTIF;
            app.Files_2.ItemsData = fileIndices;
            %
            %
            app.RUNButton.Enable = false;
            app.RUN_Detection_Button.Enable = false;
            %
        end

        % Button pushed function: SetTrackFile
        function SetTrackFileButtonPushed(app, event)
            value = app.Files.Value;            
            filename=char(app.Files.Items(value));
            selpath=strcat(string(app.TrackPath.Value),'/', string(filename)); 
            app.TrackfileTextArea.Value=filename;
            app.DetectionTextArea.Value="";
            app.RUN_Detection_Button.Enable = false;
            % fill the analysisInfo Structure with all necessary
            % information
            fillAnalysisInfo(app,'Amira Track');

            app.StatusTextArea.Value = 'Load Tracks';
            drawnow;
            % call the organizeAmiraTracks function
            [app.analysisInfo, app.selectedTracks] = organizeAmiraTracks(selpath, app.analysisInfo);
%
            app.N_rawTracks.Value = app.analysisInfo.N_rawTracks;
            app.N_selectLength.Value = app.analysisInfo.N_selectLength;
            app.trackstoextractEditField.Value = app.N_selectLength.Value;
            if app.trackstoextractEditField.Value > 0
                nChannels = size(app.selectedTracks(1).x,1);
                if nChannels > 1
                    app.Channel2trackedCheckBox.Value = true;
                end
                if nChannels > 2
                    app.Channel3trackedCheckBox.Value = true;
                end
%
                app.StatusTextArea.Value = 'Done';
                app.SaveorganizedtracksButton.Enable = true;
                if (app.N_selectLength.Value > 0 && ~isequal(app.ImageFolderTextArea.Value,""))
                    app.RUNButton.Enable = true;
                end
            else
                app.StatusTextArea.Value = 'No tracks found ..';
            end
        end

        % Button pushed function: ChangeFilesButton_2
        function ChangeFilesButton_2Pushed(app, event)
            
            selpath = uigetdir(app.startpath);
            if selpath ~= 0
                app.analysisPath.Value = char(selpath);
                app.startpath = selpath;
            end
        end

        % Button pushed function: SaveorganizedtracksButton
        function SaveorganizedtracksButtonPushed(app, event)

            selectTracks = app.selectedTracks;
            uisave('selectTracks');
            if app.SaveTracksasTXTCheckBox.Value
                [file,path] = uiputfile('*.txt');
                if isequal(file,0) || isequal(path,0)
                   return
                else
                    textfilename = char(fullfile(path,file));
                    saveTracksText(app,textfilename);
                end

            end
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            %
            app.fillAnalysisInfo('Amira Track');
            app.StatusTextArea.Value = 'Extract ROIs ..';
            app.analysisInfo = extractAmiraROIs(app.selectedTracks,app.analysisInfo);
            app.StatusTextArea.Value = 'Done';
        end

        % Button pushed function: SetImageFolder
        function SetImageFolderButtonPushed(app, event)
            %value = app.Files.Value;            
            %filename=char(app.Files.Items(value));
            selpath=string(app.Path_2.Value); 
            app.ImageFolderTextArea.Value=selpath;
            app.N_images_1.Value = app.N_images.Value;
            %
            app.imageFileNames = app.Files_2.Items;
            app.analysisInfo.channel1Path = selpath;
            app.analysisInfo.filenames = app.Files_2.Items;
            if (app.trackstoextractEditField.Value > 0 && ~isequal(app.ImageFolderTextArea.Value,''))
                mode = app.selectedTracks(1).mode;
                if mode == 0
                    app.RUN_Detection_Button.Enable = true;
                else
                    app.RUNButton.Enable = true;
                end
            end
        end

        % Button pushed function: ChangeImageFolderButton
        function ChangeImageFolderButtonPushed(app, event)
            %value = app.Files.Value;
            selpath = uigetdir(app.startpath);
            if selpath ~=0
                app.Path_2.Value = selpath;
                fileList = readFiles(app,selpath)';
                fileListTIF = fileList(contains(fileList,".tif"));
                app.nFrames=size(fileListTIF,1);
                app.N_images.Value = app.nFrames;
                fileIndices=[1:app.nFrames];
                %disp(fitsFileList)
                app.Files_2.Items = fileListTIF;
                app.Files_2.ItemsData = fileIndices;
                app.startpath = selpath;
            end
        end

        % Button pushed function: SetImageFolder_2
        function SetImageFolder_2ButtonPushed(app, event)
            %value = app.Files.Value;            
            %filename=char(app.Files.Items(value));
            selpath=string(app.Path_2.Value); 
            app.ImageFolderTextAreaChannel2.Value=selpath;
            %app.nFrames = Integer(app.nFiles);
            %
            app.imageFileNamesChannel2 = app.Files_2.Items;
            app.N_images_2.Value = app.N_images.Value;
            app.nFrames2 = app.N_images.Value;
            app.analysisInfo.channel2Path = selpath;
            app.analysisInfo.imageFileNamesChannel2 = app.Files_2.Items;
            
        end

        % Button pushed function: SetImageFolder_3
        function SetImageFolder_3ButtonPushed(app, event)
            %value = app.Files.Value;            
            %filename=char(app.Files.Items(value));
            selpath=string(app.Path_2.Value); 
            app.ImageFolderTextAreaChannel3.Value=selpath;
            %app.nFrames = Integer(app.nFiles);
            %
            app.imageFileNamesChannel3 = app.Files_2.Items;
            app.N_images_3.Value = app.N_images.Value;
            app.nFrames3 = app.N_images.Value;
            app.analysisInfo.channel3Path = selpath;
            app.analysisInfo.imageFileNamesChannel3 = app.Files_2.Items;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.ImageFolderTextAreaChannel2.Value="";
            app.N_images_2.Value = 0;
            app.nFrames2 = 0;
            app.Ch2dxEditField.Value =0;
            app.Ch2dyEditField.Value =0;
        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
            app.ImageFolderTextAreaChannel3.Value="";
            app.N_images_3.Value = 0;
            app.nFrames3 = 0;
            app.Ch3dxEditField.Value =0;
            app.Ch3dyEditField.Value =0;
        end

        % Button pushed function: SaveDefaultsButton
        function SaveDefaultsButtonPushed(app, event)
            defaults.imagefolder1 = app.Path_2.Value;
            defaults.trackpath = app.TrackPath.Value;
            defaults.analysispath = app.analysisPath.Value;
            defaults.minlength = app.setLength.Value;
            defaults.cat1 = app.CheckBox.Value;
            defaults.cat2 = app.CheckBox_2.Value;
            defaults.cat3 = app.CheckBox_3.Value;
            defaults.cat4 = app.CheckBox_4.Value;
            defaults.cat5 = app.CheckBox_5.Value;
            defaults.cat6 = app.CheckBox_6.Value;
            defaults.cat7 = app.CheckBox_7.Value;
            defaults.cat8 = app.CheckBox_8.Value;
            defaults.roiwidth = app.ROIwidthEditField.Value;
            defaults.roidepth = app.ROIdepthEditField.Value;
            %
            save('virusbrowser_def.mat','defaults');
        end

        % Button pushed function: SetDetectionTrackFile
        function SetDetectionTrackFileButtonPushed(app, event)
            value = app.Files.Value;            
            filename=char(app.Files.Items(value));
            selpath=strcat(string(app.TrackPath.Value),'/', string(filename)); 
            app.DetectionTextArea.Value = filename;
            app.TrackfileTextArea.Value = "";
            app.RUNButton.Enable = false;
            app.StatusTextArea.Value = 'Load Tracks';
            drawnow;
            trackFileData = load(selpath);
            if app.ConfocalCheckBox.Value
                app.rawTracks = trackFileData.pstruct;
            else
                app.rawTracks = trackFileData.frameInfo;
            end
            %
            %
            s=size(app.rawTracks,2);
            app.trackstoextractEditField.Value = s;
            app.resultFrames.Value = s;
            nChannels = size(app.rawTracks(1).x,1);
            trackedChannels = 1;
            if nChannels > 1
                trackedChannels =2;
                app.Channel2trackedCheckBox.Value = true;
            end
            if nChannels > 2
                trackedChannels =3;
                app.Channel3trackedCheckBox.Value = true;
            end
            %
            app.StatusTextArea.Value = 'Organizing Tracks';
            drawnow;
            app.selectedTracks=struct([]);
            for index = 1:s  %build a track with detection results for each frame
                %
                j=index;
                %
                numberDetections = size(app.rawTracks(index).isPSF,2);
                if app.CheckBox_9.Value
                    isPSF1 = app.rawTracks(index).isPSF(1,:);
                else
                    isPSF1 = ones(1,numberDetections);
                end
                
                if (nChannels > 1) && app.CheckBox_10.Value 
                    isPSF2 = app.rawTracks(index).isPSF(2,:);
                else
                    isPSF2 = ones(1,numberDetections);
                end
                if (nChannels > 2) && app.CheckBox_11.Value 
                    isPSF3 = app.rawTracks(index).isPSF(3,:);
                else
                    isPSF3 = ones(1,numberDetections);
                end
                switch app.SelectionDropDown.Value
                    case "AND"
                        DetectionIndices = find(isPSF1 & isPSF2 & isPSF3);
                    case "OR"
                        DetectionIndices = find(isPSF1 | isPSF2 | isPSF3);
                end
                detectionTrackLength = size(DetectionIndices,2);
                %
                app.selectedTracks(index).originalIndex = index;
                app.selectedTracks(index).f = (1:detectionTrackLength)';
                app.selectedTracks(index).trackChannels = trackedChannels;
                %
                %
                app.selectedTracks(index).x = (app.rawTracks(j).x(1,DetectionIndices))';
                app.selectedTracks(index).y = (app.rawTracks(j).y(1,DetectionIndices))';
                app.selectedTracks(index).z = (app.rawTracks(j).z(1,DetectionIndices))';
                app.selectedTracks(index).A = (app.rawTracks(j).A(1,DetectionIndices))';
                %
                app.selectedTracks(index).dx = (app.rawTracks(j).x_pstd(1,DetectionIndices))';
                app.selectedTracks(index).dy = (app.rawTracks(j).y_pstd(1,DetectionIndices))';
                app.selectedTracks(index).dz = (app.rawTracks(j).z_pstd(1,DetectionIndices))';
                app.selectedTracks(index).dA = (app.rawTracks(j).A_pstd(1,DetectionIndices))';
                %
                if (nChannels > 1) && app.CheckBox_10.Value
                    app.selectedTracks(index).x2 = (app.rawTracks(j).x(2,DetectionIndices))';
                    app.selectedTracks(index).y2 = (app.rawTracks(j).y(2,DetectionIndices))';
                    app.selectedTracks(index).z2 = (app.rawTracks(j).z(2,DetectionIndices))';
                    app.selectedTracks(index).A2 = (app.rawTracks(j).A(2,DetectionIndices))';
                    %
                    app.selectedTracks(index).dx2 = (app.rawTracks(j).x_pstd(2,DetectionIndices))';
                    app.selectedTracks(index).dy2 = (app.rawTracks(j).y_pstd(2,DetectionIndices))';
                    app.selectedTracks(index).dz2 = (app.rawTracks(j).z_pstd(2,DetectionIndices))';
                    app.selectedTracks(index).dA2 = (app.rawTracks(j).A_pstd(2,DetectionIndices))';
                end
                if (nChannels > 2) && app.CheckBox_11.Value
                    app.selectedTracks(index).x3 = (app.rawTracks(j).x(3,DetectionIndices))';
                    app.selectedTracks(index).y3 = (app.rawTracks(j).y(3,DetectionIndices))';
                    app.selectedTracks(index).z3 = (app.rawTracks(j).z(3,DetectionIndices))';
                    app.selectedTracks(index).A3 = (app.rawTracks(j).A(3,DetectionIndices))';
                    %
                    app.selectedTracks(index).dx3 = (app.rawTracks(j).x_pstd(3,DetectionIndices))';
                    app.selectedTracks(index).dy3 = (app.rawTracks(j).y_pstd(3,DetectionIndices))';
                    app.selectedTracks(index).dz3 = (app.rawTracks(j).z_pstd(3,DetectionIndices))';
                    app.selectedTracks(index).dA3 = (app.rawTracks(j).A_pstd(3,DetectionIndices))';
                end
                %app.selectedTracks(index).catIdx = app.rawTracks(j).catIdx;
                app.selectedTracks(index).tag = 0;
                app.selectedTracks(index).mode = 0;
                %
                xMin = round(min((app.rawTracks(j).x(1,DetectionIndices))'));
                xMax = round(max((app.rawTracks(j).x(1,DetectionIndices))'));
                yMin = round(min((app.rawTracks(j).y(1,DetectionIndices))'));
                yMax = round(max((app.rawTracks(j).y(1,DetectionIndices))'));
                zMin = round(min((app.rawTracks(j).z(1,DetectionIndices))'));
                zMax = round(max((app.rawTracks(j).z(1,DetectionIndices))'));
                app.selectedTracks(index).staticROIlimits = [xMin xMax yMin yMax zMin zMax];
                app.selectedTracks(index).start = 1;
                app.selectedTracks(index).end = detectionTrackLength;
                app.selectedTracks(index).channels = 1;
            end
            app.StatusTextArea.Value = 'Done';
            app.SaveorganizedtracksButton.Enable = true;
            if (s > 0 && ~isequal(app.ImageFolderTextArea.Value,""))
                app.RUN_Detection_Button.Enable = true;
            end
        end

        % Button pushed function: RUN_Detection_Button
        function RUN_Detection_ButtonPushed(app, event)
             %
            channel2 =  ~isequal(app.ImageFolderTextAreaChannel2.Value,"");
            channel3 =  ~isequal(app.ImageFolderTextAreaChannel3.Value,"");
            imageName = app.imageFileNames(1);
            imagePath = strcat(string(app.ImageFolderTextArea.Value),'/',string(imageName));
            series1 = loadStack(app, char(imagePath));%     loadStack is the function that loads one stack and can be adapted
            xMax = app.imageSize(1);
            yMax = app.imageSize(2);
            zMax = app.imageSize(3);
            mode = 1;
            if app.nFrames < app.trackstoextractEditField.Value
                app.trackstoextractEditField.Value = app.nFrames;
            end
            if channel2 && (app.nFrames2 < app.trackstoextractEditField.Value)
                app.trackstoextractEditField.Value = app.nFrames2;
            end
            if channel3 && (app.nFrames3 < app.trackstoextractEditField.Value)
                app.trackstoextractEditField.Value = app.nFrames3;
            end
            channels = 1+channel2*2+channel3*4;   %binary code for which channels are used
            % Creating Folder Name from Timestamp
            resultFolder = strcat(string(app.analysisPath.Value),"/",datestr(now,'yyyymmdd_HHMM_'),"ROIS",string(app.NameEditField.Value));
            mkdir(char(resultFolder));
            resultFileName1 = strcat(resultFolder,"/",datestr(now,'yyyymmdd_HHMM_'),"ROIS",string(app.NameEditField.Value));
            resultFileName2 = strcat(resultFolder,"/",datestr(now,'yyyymmdd_HHMM_'),"SLTR",string(app.NameEditField.Value));
            % ROI size in pixel (odd number with central pixel being the
            % determined position
            width = floor(app.ROIwidthEditField.Value / 2);
            roiSize = 2*width+1;
            if roiSize > xMax
                roiSizeX = xMax;
            else 
                roiSizeX = roiSize;
            end
            if roiSize > yMax
                roiSizeY = yMax;
            else 
                roiSizeY = roiSize;
            end
            if roiSize > zMax
                roiSizeZ = zMax;
            else 
                roiSizeZ = roiSize;
            end
            depth = floor(app.ROIdepthEditField.Value / 2);
            if zMax ==1
                depth = 0;
            end
            
            % 2D arrays (rows: # of images, collumn: # of selected
            % trajectories)  for x, y, and z positions
            % and trackTime with frame number
            %
            trackTime = zeros(app.nFrames,app.trackstoextractEditField.Value);
            %
            %            
            %
                app.StatusTextArea.Value = strcat("Roi Sizes X Y Z: ",string([roiSizeX roiSizeY roiSizeZ]));
                drawnow;
            for index = 1:app.trackstoextractEditField.Value
                startindex = app.selectedTracks(index).start;
                endindex = app.selectedTracks(index).end;
                trackLength = endindex - startindex +1;
                trackTimes = (1:trackLength)';
                trackTime(startindex:endindex,index) = trackTimes;
                %
                %
                % Prepare and initialize Structure with Arrays for ROI data
                %
                allROIs(index).xy = zeros(roiSizeY, roiSizeX, trackLength);
                allROIs(index).xz = zeros(roiSizeZ, roiSizeX, trackLength);
                allROIs(index).yz = zeros(roiSizeY, roiSizeZ, trackLength);
                %
                %sXsize = app.selectedTracks(index).staticROIlimits(2) - app.selectedTracks(index).staticROIlimits(1) + 2 * width+1;
                %sYsize = app.selectedTracks(index).staticROIlimits(4) - app.selectedTracks(index).staticROIlimits(3) + 2 * width+1;
                %sZsize = app.selectedTracks(index).staticROIlimits(6) - app.selectedTracks(index).staticROIlimits(5) + 2 * width+1;
                %if sZsize > zMax
                %    sZsize = zMax;
                %end
                %
%                 allROIs(index).xyS = zeros(sYsize,sXsize, trackLength);
%                 allROIs(index).xzS = zeros(sZsize, sXsize, trackLength);
%                 allROIs(index).yzS = zeros(sYsize,sZsize,trackLength);
                %
                app.selectedTracks(index).roisizes = [roiSizeX roiSizeY roiSizeZ roiSizeX roiSizeY roiSizeZ trackLength];
                app.selectedTracks(index).channels = channels;
            end
%
            %  2. Channel ?
            if channel2
                %
               
                %app.StatusTextArea.Value = 'Initializing ROI arrays channel2';
                %
                for index = 1:app.trackstoextractEditField.Value
                    startindex = app.selectedTracks(index).start;
                    endindex = app.selectedTracks(index).end;
                    trackLength = endindex - startindex +1;
                    trackTimes = (1:trackLength)';
                    trackTime(startindex:endindex,index) = trackTimes;
                    %
                    %
                    % Prepare and initialize Structure with Arrays for ROI data
                    %
                    allROIs(index).xy2 = zeros(roiSizeY, roiSizeX, trackLength);
                    allROIs(index).xz2 = zeros(roiSizeZ, roiSizeX, trackLength);
                    allROIs(index).yz2 = zeros(roiSizeY, roiSizeZ, trackLength);
                    %
%                     sXsize = app.selectedTracks(index).roisizes(4);
%                     sYsize = app.selectedTracks(index).roisizes(5);
%                     sZsize = app.selectedTracks(index).roisizes(6);
%                     %
%                     allROIs(index).xyS2 = zeros(sYsize,sXsize, trackLength);
%                     allROIs(index).xzS2 = zeros(sZsize, sXsize, trackLength);
%                     allROIs(index).yzS2 = zeros(sYsize,sZsize,trackLength);
                    %
                    %app.selectedTracks(index).roisizes = [roiSize sXsize sYsize sZsize trackLength];
                end
            end
%            size(allROIs(1).xy2) 
            %  3. Channel?
            if channel3
                %
                startindex = app.selectedTracks(index).start;
                endindex = app.selectedTracks(index).end;
                trackLength = endindex - startindex +1;
                trackTimes = (1:trackLength)';
                trackTime(startindex:endindex,index) = trackTimes;
                %
               
                %app.StatusTextArea.Value = 'Initializing ROI arrays channel3';
                %
                for index = 1:app.trackstoextractEditField.Value
                    startindex = app.selectedTracks(index).start;
                    endindex = app.selectedTracks(index).end;
                    trackLength = endindex - startindex +1;
                    trackTimes = (1:trackLength)';
                    trackTime(startindex:endindex,index) = trackTimes;
                    %
                    % Prepare and initialize Structure with Arrays for ROI data
                    %
                    allROIs(index).xy3 = zeros(roiSizeY, roiSizeX, trackLength);
                    allROIs(index).xz3 = zeros(roiSizeZ, roiSizeX, trackLength);
                    allROIs(index).yz3 = zeros(roiSizeY, roiSizeZ, trackLength);
                    %
%                     sXsize = app.selectedTracks(index).roisizes(4);
%                     sYsize = app.selectedTracks(index).roisizes(5);
%                     sZsize = app.selectedTracks(index).roisizes(6);
%                     %
%                     allROIs(index).xyS3 = zeros(sYsize,sXsize, trackLength);
%                     allROIs(index).xzS3 = zeros(sZsize, sXsize, trackLength);
%                     allROIs(index).yzS3 = zeros(sYsize,sZsize,trackLength);
                    %
                    %app.selectedTracks(index).roisizes = [roiSize sXsize sYsize sZsize trackLength];
                end
            end
            
        %Extracting bitmaps by going through all images  ,  Each image is
        %equivalent to a track in this mode
            %
            %app.StatusTextArea.Value = 'Indexing Time Array';
            %
            for index = 1 : app.trackstoextractEditField.Value  %    Image counter
                %if sum(trackTime(index,:)) ~= 0  %  check if any tracks are found in this image
                    app.ImageNumberEditField.Value = index;
                    drawnow;
                        imageName = app.imageFileNames(index);
                        imagePath = strcat(string(app.ImageFolderTextArea.Value),'/',string(imageName));
                        app.StatusTextArea.Value = char(imagePath);
                        series1 = loadStack(app, char(imagePath));%                         loadStack is the function that loads one stack and can be adapted
                        xMax = app.imageSize(1);
                        yMax = app.imageSize(2);
                        zMax = app.imageSize(3);
                        series1_planeCount = zMax;
                        imageStack = zeros(yMax, xMax, zMax);
                        for zIndex = 1 : series1_planeCount  %  building 3D image stack
                            if app.ConfocalCheckBox.Value
                                imageStack(:,:,zIndex) = series1{zIndex,1};
                            else
                                imageStack(:,:,series1_planeCount-zIndex+1) = series1{zIndex,1};
                            end
                        end
                    
                    if app.WholeimageprojectionCheckBox.Value
                            proImg.XY = max(imageStack,[],3);
                            proImg.XZ = (squeeze(max(imageStack,[],1)))';
                            proImg.YZ = squeeze(max(imageStack,[],2));
                            saveProjectedImages(app,proImg,1,resultFolder,index);
                    end
                    %  Open 2. channel stack if necessary
                    if channel2 && (index <= app.nFrames2)
                              imageName2 = app.imageFileNamesChannel2(index);
                              imagePath2 = strcat(string(app.ImageFolderTextAreaChannel2.Value),'/',string(imageName2));
                              app.StatusTextArea.Value = char(imagePath2  );
                              series2 = loadStack(app, char(imagePath2));
                              %
                              xMax = app.imageSize(1);
                              yMax = app.imageSize(2);
                              zMax = app.imageSize(3);
                              imageStack2 = zeros(yMax, xMax, zMax);
                              series2_planeCount = zMax;
                              for zIndex = 1 : series2_planeCount
                                  if app.ConfocalCheckBox.Value
                                        imageStack2(:,:,zIndex) = series2{zIndex,1};
                                  else
                                        imageStack2(:,:,series2_planeCount-zIndex+1) = series2{zIndex,1};
                                  end
                              end
                        
                        if app.WholeimageprojectionCheckBox.Value
                            proImg.XY = max(imageStack2,[],3);
                            proImg.XZ = (squeeze(max(imageStack2,[],1)))';
                            proImg.YZ = squeeze(max(imageStack2,[],2));
                            saveProjectedImages(app,proImg,2,resultFolder,index);
                        end
                        
                    end
                    % Open 3. channel stack if necessary
                    if channel3 && (index <= app.nFrames3)
                            imageName3 = app.imageFileNamesChannel3(index);
                            %pat = "_" + digitsPattern;
                            %numFilename3 = extract(extract(string(imageName3),pat),digitsPattern);
                            %timeIndex3 = str2double(numFilename3(1));
                            imagePath3 = strcat(string(app.ImageFolderTextAreaChannel3.Value),'/',string(imageName3));
                            app.StatusTextArea.Value = char(imagePath3  );
                            %
                            series3 = loadStack(app, char(imagePath3));
                            xMax = app.imageSize(1);
                            yMax = app.imageSize(2);
                            zMax = app.imageSize(3);
                            imageStack3 = zeros(yMax, xMax, zMax);
                            series3_planeCount = zMax;
                            for zIndex = 1 : series3_planeCount
                                if app.ConfocalCheckBox.Value
                                        imageStack3(:,:,zIndex) = series3{zIndex,1};
                                  else
                                        imageStack3(:,:,series3_planeCount-zIndex+1) = series3{zIndex,1};
                                end
                            end
                        if app.WholeimageprojectionCheckBox.Value
                            proImg.XY = max(imageStack3,[],3);
                            proImg.XZ = (squeeze(max(imageStack3,[],1)))';
                            proImg.YZ = squeeze(max(imageStack3,[],2));
                            saveProjectedImages(app,proImg,3,resultFolder,index);
                        end
                        
                    end
                    %series1_plane10 = series1{10, 1};
                    %imshow(series1_plane10, []);
                    DetectionTrackLength = size(app.selectedTracks(index).x,1);
                     for index2 = 1 : DetectionTrackLength   %  all ROIs / detections in one frame / track
                        app.TrackNumberEditField.Value = index2;
                        drawnow;
                        if ~isnan(app.selectedTracks(index).x(index2,1)) 
                            x = round(app.selectedTracks(index).x(index2,1));
                            y = round(app.selectedTracks(index).y(index2,1));
                            z = round(app.selectedTracks(index).z(index2,1));
                            %  Determin ROI limits
                            roiLimits12 = roiLimits(app,width,depth,xMax,yMax,zMax,x,y,z);
                            xStart = roiLimits12(1);
                            xEnd = roiLimits12(2);
                            yStart = roiLimits12(3);
                            yEnd = roiLimits12(4);
                            zStart = roiLimits12(5);
                            zEnd = roiLimits12(6);
                            xPStart = roiLimits12(7);
                            xPEnd = roiLimits12(8);
                            yPStart = roiLimits12(9);
                            yPEnd = roiLimits12(10);
                            zPStart = roiLimits12(11);
                            zPEnd = roiLimits12(12);
                            %
                            %
                            sectionXY = imageStack(yStart:yEnd,xStart:xEnd,zPStart:zPEnd);
                            allROIs(index).xy(1:yEnd-yStart+1,1:xEnd-xStart+1,index2) = max(sectionXY,[],3);
                            sectionXZ = imageStack(yPStart:yPEnd,xStart:xEnd,zStart:zEnd);
                            allROIs(index).xz(1:zEnd-zStart+1,1:xEnd-xStart+1,index2) = (squeeze(max(sectionXZ,[],1)))';
                            sectionYZ = imageStack(yStart:yEnd,xPStart:xPEnd,zStart:zEnd);
                            allROIs(index).yz(1:yEnd-yStart+1,1:zEnd-zStart+1,index2) = squeeze(max(sectionYZ,[],2));
                            %
                            %  Channel 2
                            if channel2 && (index <= app.nFrames2)
                                %
                                if  app.Channel2trackedCheckBox.Value
                                    x = round(app.selectedTracks(index).x(index2,2));
                                    y = round(app.selectedTracks(index).y(index2,2));
                                    z = round(app.selectedTracks(index).z(index2,2));
                                end
                                %  Correction x,y
                                x = x + app.Ch2dxEditField.Value;
                                y = y + app.Ch2dyEditField.Value;
                                %  Determin ROI limits
                                roiLimits12 = roiLimits(app,width,depth,xMax,yMax,zMax,x,y,z);
                                xStart = roiLimits12(1);
                                xEnd = roiLimits12(2);
                                yStart = roiLimits12(3);
                                yEnd = roiLimits12(4);
                                zStart = roiLimits12(5);
                                zEnd = roiLimits12(6);
                                xPStart = roiLimits12(7);
                                xPEnd = roiLimits12(8);
                                yPStart = roiLimits12(9);
                                yPEnd = roiLimits12(10);
                                zPStart = roiLimits12(11);
                                zPEnd = roiLimits12(12);
                                
                                sectionXY2 = imageStack2(yStart:yEnd,xStart:xEnd,zPStart:zPEnd);
                                allROIs(index).xy2(1:yEnd-yStart+1,1:xEnd-xStart+1,index2) = max(sectionXY2,[],3);
                                sectionXZ2 = imageStack2(yPStart:yPEnd,xStart:xEnd,zStart:zEnd);
                                allROIs(index).xz2(1:zEnd-zStart+1,1:xEnd-xStart+1,index2) =(squeeze(max(sectionXZ2,[],1)))';
                                sectionYZ2 = imageStack2(yStart:yEnd,xPStart:xPEnd,zStart:zEnd);
                                allROIs(index).yz2(1:yEnd-yStart+1,1:zEnd-zStart+1,index2) = squeeze(max(sectionYZ2,[],2));
                                %
                            end
                            %  Channel 3
                            if channel3 && (index <= app.nFrames3)
                                %
                                if  app.Channel3trackedCheckBox.Value
                                    x = round(app.selectedTracks(index).x(index2,3));
                                    y = round(app.selectedTracks(index).y(index2,3));
                                    z = round(app.selectedTracks(index).z(index2,3));
                                end
                                %  Correction x,y
                                x = x + app.Ch3dxEditField.Value;
                                y = y + app.Ch3dyEditField.Value;
                                %  Determin ROI limits
                                roiLimits12 = roiLimits(app,width,depth,xMax,yMax,zMax,x,y,z);
                                xStart = roiLimits12(1);
                                xEnd = roiLimits12(2);
                                yStart = roiLimits12(3);
                                yEnd = roiLimits12(4);
                                zStart = roiLimits12(5);
                                zEnd = roiLimits12(6);
                                xPStart = roiLimits12(7);
                                xPEnd = roiLimits12(8);
                                yPStart = roiLimits12(9);
                                yPEnd = roiLimits12(10);
                                zPStart = roiLimits12(11);
                                zPEnd = roiLimits12(12);

                                sectionXY3 = imageStack3(yStart:yEnd,xStart:xEnd,zPStart:zPEnd);
                                allROIs(index).xy3(1:yEnd-yStart+1,1:xEnd-xStart+1,index2) = max(sectionXY3,[],3);
                                sectionXZ3 = imageStack3(yPStart:yPEnd,xStart:xEnd,zStart:zEnd);
                                allROIs(index).xz3(1:zEnd-zStart+1,1:xEnd-xStart+1,index2) =(squeeze(max(sectionXZ3,[],1)))';
                                sectionYZ3 = imageStack3(yStart:yEnd,xPStart:xPEnd,zStart:zEnd);
                                allROIs(index).yz3(1:yEnd-yStart+1,1:zEnd-zStart+1,index2) = squeeze(max(sectionYZ3,[],2));
                                %
                            end
%                     % static ROI sizes
%                     if (app.selectedTracks(index).staticROIlimits(1) - width) < 1
%                         xSStart = 1;
%                     else
%                         xSStart = app.selectedTracks(index).staticROIlimits(1) - width;
%                     end
%                     if (app.selectedTracks(index).staticROIlimits(2) + width) > xMax
%                         xSEnd = xMax;
%                     else
%                         xSEnd = app.selectedTracks(index).staticROIlimits(2) + width;
%                     end
%                     if (app.selectedTracks(index).staticROIlimits(3) - width) < 1
%                         ySStart = 1;
%                     else
%                         ySStart = app.selectedTracks(index).staticROIlimits(3) - width;
%                     end
%                     if (app.selectedTracks(index).staticROIlimits(4) + width) > yMax
%                         ySEnd = yMax;
%                     else
%                         ySEnd = app.selectedTracks(index).staticROIlimits(4) + width;
%                     end
%                     if (app.selectedTracks(index).staticROIlimits(5) - width) < 1
%                         zSStart = 1;
%                     else
%                         zSStart = app.selectedTracks(index).staticROIlimits(5) - width;
%                     end
%                     if (app.selectedTracks(index).staticROIlimits(6) + width) > zMax
%                         zSEnd = zMax;
%                     else
%                         zSEnd = app.selectedTracks(index).staticROIlimits(6) + width;
%                     end
%                     %
%                     sectionXY = imageStack(ySStart:ySEnd,xSStart:xSEnd,zPStart:zPEnd);
%                     allROIs(index).xyS(1:ySEnd-ySStart+1,1:xSEnd-xSStart+1,index2) =  max(sectionXY,[],3);
%                     sectionXZ = imageStack(yPStart:yPEnd,xSStart:xSEnd,zSStart:zSEnd);
%                     allROIs(index).xzS(1:zSEnd-zSStart+1,1:xSEnd-xSStart+1,index2) = (squeeze(max(sectionXZ,[],1)))';
%                     sectionYZ = imageStack(ySStart:ySEnd,xPStart:xPEnd,zSStart:zSEnd);
%                     allROIs(index).yzS(1:ySEnd-ySStart+1,1:zSEnd-zSStart+1,index2) = squeeze(max(sectionYZ,[],2));
%                     %
%                     %  Channel 2 static ROI
%                     if channel2 && (index <= app.nFrames2)
%                         %2
%                         sectionXY2 = imageStack2(ySStart:ySEnd,xSStart:xSEnd,zPStart:zPEnd);
%                         allROIs(index).xyS2(1:ySEnd-ySStart+1,1:xSEnd-xSStart+1,index2,index2) =  max(sectionXY2,[],3);
%                         sectionXZ2 = imageStack2(yPStart:yPEnd,xSStart:xSEnd,zSStart:zSEnd);
%                         allROIs(index).xzS2(1:zSEnd-zSStart+1,1:xSEnd-xSStart+1,index2) = (squeeze(max(sectionXZ2,[],1)))';
%                         sectionYZ2 = imageStack2(ySStart:ySEnd,xPStart:xPEnd,zSStart:zSEnd);
%                         allROIs(index).yzS2(1:ySEnd-ySStart+1,1:zSEnd-zSStart+1,index2) = squeeze(max(sectionYZ2,[],2));
%                     end
%                     %  Channel 3 static ROI
%                     if channel3 && (index <= app.nFrames3)
%                         %3
%                         sectionXY3 = imageStack3(ySStart:ySEnd,xSStart:xSEnd,zPStart:zPEnd);
%                         allROIs(index).xyS3(1:ySEnd-ySStart+1,1:xSEnd-xSStart+1,index2) = max(sectionXY3,[],3);
%                         sectionXZ3 = imageStack3(yPStart:yPEnd,xSStart:xSEnd,zSStart:zSEnd);
%                         allROIs(index).xzS3(1:zSEnd-zSStart+1,1:xSEnd-xSStart+1,index2) = (squeeze(max(sectionXZ3,[],1)))';
%                         sectionYZ3 = imageStack3(ySStart:ySEnd,xPStart:xPEnd,zSStart:zSEnd);
%                         allROIs(index).yzS3(1:ySEnd-ySStart+1,1:zSEnd-zSStart+1,index2) = squeeze(max(sectionYZ3,[],2));
%                     end
                        end
                    end
                %end
                app.Progress.Value = 100 * index/app.nFrames;
            end
            app.StatusTextArea.Value = 'Saving Tracks';
            %
            %size(allROIs(1).xy)
            %size(allROIs(2).xy)
            %
            %SAVING RESULTS
            %
            selectTracks = app.selectedTracks;
            save(char(resultFileName1),'allROIs');
            save(char(resultFileName2),'selectTracks');
           if app.SaveTracksasTXTCheckBox.Value
                resultFileName3 = strcat(resultFileName2,".txt");
                saveTracksText(app,char(resultFileName3));
            end
            %
            % save for LabView 
             app.StatusTextArea.Value = 'Saving for LabView';           %
            saveResultsForLabView(app,selectTracks,allROIs,resultFolder,0);
            app.StatusTextArea.Value = 'DONE';
        end

        % Callback function
        function EMPTYButtonPushed(app, event)
            numbPrevItems = size(app.ToDoListListBox.Items,2);
            app.ToDoListListBox.ItemsData = {};
            app.ToDoListListBox.Items = {};
            app.pathListTIRF = string.empty;
            app.RUNButtonTIRF_2.Enable = false;
        end

        % Button pushed function: ChangeFolderButton
        function ChangeFolderButtonPushed(app, event)
            value = app.Files.Value;
            selpath = uigetdir(app.startpath);
            if selpath ~= 0          
                 readFileList(app, selpath);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AmiraTab = uitab(app.TabGroup);
            app.AmiraTab.Title = 'Amira';
            app.PathLabel = uilabel(app.AmiraTab);
            app.PathLabel.Text = 'Path';
            app.TrackPath = uitextarea(app.AmiraTab);
            app.ChangeFolderButton = uibutton(app.AmiraTab, 'push');
            app.ChangeFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeFolderButtonPushed, true);
            app.ChangeFolderButton.Text = 'Change Folder';
            app.FilesLabel = uilabel(app.AmiraTab);
            app.FilesLabel.Text = 'Files';
            app.Files = uilistbox(app.AmiraTab);
            app.SetTrackFile = uibutton(app.AmiraTab, 'push');
            app.SetTrackFile.ButtonPushedFcn = createCallbackFcn(app, @SetTrackFileButtonPushed, true);
            app.SetTrackFile.Text = '>';
            app.infileLabel = uilabel(app.AmiraTab);
            app.infileLabel.Text = '# in file';
            app.N_rawTracks = uieditfield(app.AmiraTab, 'numeric');
            app.N_rawTracks.Limits = [0 Inf];
            app.N_rawTracks.ValueDisplayFormat = '%.0f';
            app.TrackfileTextAreaLabel = uilabel(app.AmiraTab);
            app.TrackfileTextAreaLabel.Text = 'Track file';
            app.TrackfileTextArea = uitextarea(app.AmiraTab);
            app.minlengthLabel = uilabel(app.AmiraTab);
            app.minlengthLabel.Text = 'min length';
            app.setLength = uieditfield(app.AmiraTab, 'numeric');
            app.setLength.Limits = [1 Inf];
            app.setLength.ValueDisplayFormat = '%.0f';
            app.setLength.Value = 35;
            app.minlengthLabel_2 = uilabel(app.AmiraTab);
            app.minlengthLabel_2.Text = '> min length';
            app.N_selectLength = uieditfield(app.AmiraTab, 'numeric');
            app.N_selectLength.Limits = [0 Inf];
            app.N_selectLength.ValueDisplayFormat = '%.0f';
            app.ImagesCh1Label = uilabel(app.AmiraTab);
            app.ImagesCh1Label.Text = 'Images Ch1';
            app.ImageFolderTextArea = uitextarea(app.AmiraTab);
            app.SetImageFolder = uibutton(app.AmiraTab, 'push');
            app.SetImageFolder.ButtonPushedFcn = createCallbackFcn(app, @SetImageFolderButtonPushed, true);
            app.SetImageFolder.Text = '<';
            app.Label = uilabel(app.AmiraTab);
            app.Label.Text = '# ';
            app.N_images = uieditfield(app.AmiraTab, 'numeric');
            app.N_images.Limits = [0 Inf];
            app.N_images.ValueDisplayFormat = '%.0f';
            app.ImagesLabel = uilabel(app.AmiraTab);
            app.ImagesLabel.Text = 'Images';
            app.Files_2 = uilistbox(app.AmiraTab);
            app.ImagePathLabel = uilabel(app.AmiraTab);
            app.ImagePathLabel.Text = 'Image Path';
            app.Path_2 = uitextarea(app.AmiraTab);
            app.ChangeImageFolderButton = uibutton(app.AmiraTab, 'push');
            app.ChangeImageFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeImageFolderButtonPushed, true);
            app.ChangeImageFolderButton.Text = 'Change Folder';
            app.ImagesCh2Label = uilabel(app.AmiraTab);
            app.ImagesCh2Label.Text = 'Images Ch2';
            app.ImageFolderTextAreaChannel2 = uitextarea(app.AmiraTab);
            app.SetImageFolder_2 = uibutton(app.AmiraTab, 'push');
            app.SetImageFolder_2.ButtonPushedFcn = createCallbackFcn(app, @SetImageFolder_2ButtonPushed, true);
            app.SetImageFolder_2.Text = '<';
            app.Label_2 = uilabel(app.AmiraTab);
            app.Label_2.Text = '# ';
            app.N_images_2 = uieditfield(app.AmiraTab, 'numeric');
            app.N_images_2.Limits = [0 Inf];
            app.N_images_2.ValueDisplayFormat = '%.0f';
            app.SetImageFolder_3 = uibutton(app.AmiraTab, 'push');
            app.SetImageFolder_3.ButtonPushedFcn = createCallbackFcn(app, @SetImageFolder_3ButtonPushed, true);
            app.SetImageFolder_3.Text = '<';
            app.ImagesCh3Label = uilabel(app.AmiraTab);
            app.ImagesCh3Label.Text = 'Images Ch3';
            app.ImageFolderTextAreaChannel3 = uitextarea(app.AmiraTab);
            app.Label_3 = uilabel(app.AmiraTab);
            app.Label_3.Text = '# ';
            app.N_images_3 = uieditfield(app.AmiraTab, 'numeric');
            app.N_images_3.Limits = [0 Inf];
            app.N_images_3.ValueDisplayFormat = '%.0f';
            app.Label_4 = uilabel(app.AmiraTab);
            app.Label_4.Text = '# ';
            app.N_images_1 = uieditfield(app.AmiraTab, 'numeric');
            app.N_images_1.Limits = [0 Inf];
            app.N_images_1.ValueDisplayFormat = '%.0f';
            app.ClearButton = uibutton(app.AmiraTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ClearButton_2 = uibutton(app.AmiraTab, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Text = 'Clear';
            app.CheckBox = uicheckbox(app.AmiraTab);
            app.CheckBox.Text = '1';
            app.CheckBox.Value = true;
            app.CheckBox_2 = uicheckbox(app.AmiraTab);
            app.CheckBox_2.Text = '2';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.AmiraTab);
            app.CheckBox_3.Text = '3';
            app.CheckBox_3.Value = true;
            app.CheckBox_4 = uicheckbox(app.AmiraTab);
            app.CheckBox_4.Text = '4';
            app.CheckBox_4.Value = true;
            app.CheckBox_5 = uicheckbox(app.AmiraTab);
            app.CheckBox_5.Text = '5';
            app.CheckBox_6 = uicheckbox(app.AmiraTab);
            app.CheckBox_6.Text = '6';
            app.CheckBox_7 = uicheckbox(app.AmiraTab);
            app.CheckBox_7.Text = '7';
            app.CheckBox_8 = uicheckbox(app.AmiraTab);
            app.CheckBox_8.Text = '8';
            app.SetDetectionTrackFile = uibutton(app.AmiraTab, 'push');
            app.SetDetectionTrackFile.ButtonPushedFcn = createCallbackFcn(app, @SetDetectionTrackFileButtonPushed, true);
            app.SetDetectionTrackFile.Text = '>';
            app.DetectionTextAreaLabel = uilabel(app.AmiraTab);
            app.DetectionTextAreaLabel.Text = 'Detection';
            app.DetectionTextArea = uitextarea(app.AmiraTab);
            app.CheckBox_9 = uicheckbox(app.AmiraTab);
            app.CheckBox_9.Text = '1';
            app.CheckBox_9.Value = true;
            app.CheckBox_10 = uicheckbox(app.AmiraTab);
            app.CheckBox_10.Text = '2';
            app.CheckBox_10.Value = true;
            app.CheckBox_11 = uicheckbox(app.AmiraTab);
            app.CheckBox_11.Text = '3';
            app.CheckBox_11.Value = true;
            app.CatIDLabel = uilabel(app.AmiraTab);
            app.CatIDLabel.Text = 'Cat ID:';
            app.IsPSFLabel = uilabel(app.AmiraTab);
            app.IsPSFLabel.Text = 'Is PSF:';
            app.resultframesLabel = uilabel(app.AmiraTab);
            app.resultframesLabel.Text = 'result frames';
            app.resultFrames = uieditfield(app.AmiraTab, 'numeric');
            app.resultFrames.Limits = [0 Inf];
            app.resultFrames.ValueDisplayFormat = '%.0f';
            app.Label_5 = uilabel(app.AmiraTab);
            app.Label_5.Text = '';
            app.SelectionDropDown = uidropdown(app.AmiraTab);
            app.SelectionDropDown.Items = {'AND', 'OR'};
            app.SelectionDropDown.Value = 'AND';
            app.dxEditFieldLabel = uilabel(app.AmiraTab);
            app.dxEditFieldLabel.Text = 'dx';
            app.Ch2dxEditField = uieditfield(app.AmiraTab, 'numeric');
            app.dyEditFieldLabel = uilabel(app.AmiraTab);
            app.dyEditFieldLabel.Text = 'dy';
            app.Ch2dyEditField = uieditfield(app.AmiraTab, 'numeric');
            app.dxEditFieldLabel_2 = uilabel(app.AmiraTab);
            app.dxEditFieldLabel_2.Text = 'dx';
            app.Ch3dxEditField = uieditfield(app.AmiraTab, 'numeric');
            app.dyEditFieldLabel_2 = uilabel(app.AmiraTab);
            app.dyEditFieldLabel_2.Text = 'dy';
            app.Ch3dyEditField = uieditfield(app.AmiraTab, 'numeric');
            app.ConfocalCheckBox = uicheckbox(app.AmiraTab);
            app.ConfocalCheckBox.Text = 'Confocal';
            app.TrackInfoTab = uitab(app.TabGroup);
            app.TrackInfoTab.Title = 'Track Info';
            app.Channel2trackedCheckBox = uicheckbox(app.TrackInfoTab);
            app.Channel2trackedCheckBox.Text = 'Channel 2 tracked';
            app.Channel3trackedCheckBox = uicheckbox(app.TrackInfoTab);
            app.Channel3trackedCheckBox.Text = 'Channel 3 tracked';
            app.ChangeFilesButton_2 = uibutton(app.UIFigure, 'push');
            app.ChangeFilesButton_2.ButtonPushedFcn = createCallbackFcn(app, @ChangeFilesButton_2Pushed, true);
            app.ChangeFilesButton_2.Text = 'Change Analysis Folder';
            app.AnalysisPathLabel = uilabel(app.UIFigure);
            app.AnalysisPathLabel.Text = 'Analysis Path';
            app.analysisPath = uitextarea(app.UIFigure);
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.SaveDefaultsButton = uibutton(app.UIFigure, 'push');
            app.SaveDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDefaultsButtonPushed, true);
            app.SaveDefaultsButton.Text = 'Save Defaults';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.TrackEditFieldLabel = uilabel(app.UIFigure);
            app.TrackEditFieldLabel.Text = 'Track #';
            app.TrackNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImageEditFieldLabel = uilabel(app.UIFigure);
            app.ImageEditFieldLabel.Text = 'Image #';
            app.ImageNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.progressLabel = uilabel(app.UIFigure);
            app.progressLabel.Text = 'progress';
            app.Progress = uieditfield(app.UIFigure, 'numeric');
            app.Progress.Limits = [0 Inf];
            app.Progress.ValueDisplayFormat = '%.1f';
            app.ROIwidthEditFieldLabel = uilabel(app.UIFigure);
            app.ROIwidthEditFieldLabel.Text = 'ROI width';
            app.ROIwidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.ROIwidthEditField.Limits = [3 Inf];
            app.ROIwidthEditField.RoundFractionalValues = 'on';
            app.ROIwidthEditField.ValueDisplayFormat = '%.0f';
            app.ROIwidthEditField.Value = 11;
            app.ROIdepthEditFieldLabel = uilabel(app.UIFigure);
            app.ROIdepthEditFieldLabel.Text = 'ROI depth';
            app.ROIdepthEditField = uieditfield(app.UIFigure, 'numeric');
            app.ROIdepthEditField.Limits = [0 Inf];
            app.ROIdepthEditField.RoundFractionalValues = 'on';
            app.ROIdepthEditField.ValueDisplayFormat = '%.0f';
            app.SaveorganizedtracksButton = uibutton(app.UIFigure, 'push');
            app.SaveorganizedtracksButton.ButtonPushedFcn = createCallbackFcn(app, @SaveorganizedtracksButtonPushed, true);
            app.SaveorganizedtracksButton.Enable = 'off';
            app.SaveorganizedtracksButton.Text = 'Save organized tracks';
            app.trackstoextractEditFieldLabel = uilabel(app.UIFigure);
            app.trackstoextractEditFieldLabel.Text = 'tracks to extract';
            app.trackstoextractEditField = uieditfield(app.UIFigure, 'numeric');
            app.extractROIsfromallframesCheckBox = uicheckbox(app.UIFigure);
            app.extractROIsfromallframesCheckBox.Text = {'extract ROIs '; 'from all frames'};
            app.WholeimageprojectionCheckBox = uicheckbox(app.UIFigure);
            app.WholeimageprojectionCheckBox.Text = 'Whole image projection';
            app.RUN_Detection_Button = uibutton(app.UIFigure, 'push');
            app.RUN_Detection_Button.ButtonPushedFcn = createCallbackFcn(app, @RUN_Detection_ButtonPushed, true);
            app.RUN_Detection_Button.Text = 'RUN';
            app.SaveTracksasTXTCheckBox = uicheckbox(app.UIFigure);
            app.SaveTracksasTXTCheckBox.Text = 'Save Tracks as TXT';
            app.identicalxyforstaticROIsCheckBox = uicheckbox(app.UIFigure);
            app.identicalxyforstaticROIsCheckBox.Text = 'identical x y for static ROIs';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MicroscopyBrowser_Amira

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end